classdef ProjektSynchrofazory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AkcjeMenu           matlab.ui.container.Menu
        DodajurzdzenieMenu  matlab.ui.container.Menu
        UsuurzdzenieMenu    matlab.ui.container.Menu
        MapaMenu            matlab.ui.container.Menu
        UstawieniaMenu      matlab.ui.container.Menu
        GridForPlots        matlab.ui.container.GridLayout
        TextArea2           matlab.ui.control.TextArea
        TextArea2Label      matlab.ui.control.Label
        TextArea            matlab.ui.control.TextArea
        TextAreaLabel       matlab.ui.control.Label
        Panel               matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        NastpnyButton       matlab.ui.control.Button
        PoprzedniButton     matlab.ui.control.Button
        PageNumber          matlab.ui.control.Label
    end

    
    properties (Access = public)
        DataQueueRecv; % Property used to get data from function running on other thread
        DataQueueSend; % Property used to put data to function running on other thread
        Pool;  % Pool of threads
        Task; 
        NumberOfPlotsPerPage=4; % Ilosc wykresów na jednej stronie
        ColorOfPlots=[0.00 0.45 0.74]; % Kolor wykresów
        IpList=["192.168.22.103"];
        Port=4713;

    end
    
    properties (Access = private)
        OptionsDialog % Okno dialogowe dla zmiany ustawień
    end
   
    methods (Access = public,Static)
          
        function ReciveUDPdata(queueOut,queueIn,IpList,Port)
            dataPort = udpport("datagram","Ipv4","LocalPort",Port,"LocalHost",IpList,"OutputDatagramSize",96,"EnablePortSharing",true);
            while true
                if queueIn.QueueLength>0
                    break;
                elseif dataPort.DatagramsAvailableFcnCount>0
                    send(queueOut,[2,read(dataPort,1,"uint8")]);
                end
            end
            delete(dataPort);
        end

        function ReciveUDPconfig(queueOut,queueIn,IpList,Port)
             configPort=udpport("datagram","Ipv4","LocalPort",Port,"LocalHost",IpList,"OutputDatagramSize",156,"EnablePortSharing",true);
    
            while true
                if queueIn.QueueLength>0
                    break;
                elseif configPort.DatagramsAvailableFcnCount>0
                    send(queueOut,read(configPort,1,"uint8"));
                end 
            end
            delete(configPort);
        end

    end
    methods (Access = public)
        function DataQueueListener(app,data)
           ParsedData=struct();
           ParsedData.SYNC=swapbytes(typecast(uint8(data.Data(1:2)),'uint16'));%SYNC field
           ParsedData.FRAMESIZE=swapbytes(typecast(uint8(data.Data(3:4)),'uint16'));%FRAMESIZE field
           ParsedData.IDCODESOURCE=swapbytes(typecast(uint8(data.Data(5:6)),'uint16'));%IDCODE field
           ParsedData.SOC=datetime( swapbytes(typecast(uint8(data.Data(7:10)),'uint32')), 'ConvertFrom', 'posixtime');%SOC field
           ParsedData.MTQ=uint8(data.Data(11));%FRASEC field Bits 31–24 
           ParsedData.FRACSEC=swapbytes(typecast(uint8([0,data.Data(12:14)]),'uint32'));%FRASEC field Bits 23–00
           switch(ParsedData.SYNC)%Checking farme type
               case bitand(0b1010101000000011,ParsedData.SYNC,"uint16")%Data Frame

               case bitand(0b1010101000010011,ParsedData.SYNC,"uint16")%Header Frame

               case bitand(0b1010101000100011,ParsedData.SYNC,"uint16")%Configuration Frame 1

               case bitand(0b1010101000110011,ParsedData.SYNC,"uint16")%Configuration Frame 2

               case bitand(0b1010101001010011,ParsedData.SYNC,"uint16")%Configuration Frame 3

               case bitand(0b1010101001000011,ParsedData.SYNC,"uint16")%Coomand Frame

           end
           
        end
        
        function UpdateOptions(app)
            app.PageNumber.Text="a";
        end
    end
    
    methods (Access = private)
        
        function UDPconnection(app)
            app.dataPort = udpport("datagram","Ipv4","LocalPort",app.Port,"LocalHost",app.IpList,"OutputDatagramSize",96,"EnablePortSharing",true);
            app.configPort=udpport("datagram","Ipv4","LocalPort",app.Port,"LocalHost",app.IpList,"OutputDatagramSize",156,"EnablePortSharing",true);
            app.configPort.configureCallback("datagram",1,@app.ConfigPortCallback);
            app.dataPort.configureCallback("datagram",1,@app.DataPortCallback);
        end
        
        function DataPortCallback(app,src,event)
            
            data=read(app.dataPort,1,"uint8");
%             ParsedData=struct('SYNC',swapbytes(typecast(uint8(data.Data(1:2)),'uint16')),...
%                   'FRAMESIZE',swapbytes(typecast(uint8(data.Data(3:4)),'uint16')),...
%                   'IDCODE',swapbytes(typecast(uint8(data.Data(5:6)),'uint16')),...
%                   'SOC',datetime( swapbytes(typecast(uint8(data.Data(7:10)),'uint32')), 'ConvertFrom', 'posixtime','TimeZone','Europe/Warsaw' ),...
%                   'FRACSEC',data.Data(11:14),...
%                   'STAT',data.Data(15:16));
                app.TextArea.Value ="cos";
        end

        function ConfigPortCallback(app,src,event)
            configData=read(app.configPort,1,"uint8");
            app.TextArea2.Value ="cos";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Pool = parpool('local', 2);
            app.DataQueueRecv=parallel.pool.DataQueue;
            app.DataQueueSend=parallel.pool.DataQueue;
            afterEach(app.DataQueueRecv,@(data) DataQueueListener(app,data));
            parfeval(app.Pool,@app.ReciveUDPdata,0,app.DataQueueRecv,app.DataQueueSend,app.IpList,app.Port);
            parfeval(app.Pool,@app.ReciveUDPconfig,0,app.DataQueueRecv,app.DataQueueSend,app.IpList,app.Port);
            
            %app.UDPconnection;
        end

        % Menu selected function: MapaMenu
        function MapaMenuSelected(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            send(app.DataQueueSend,"Die");
            delete(gcp("nocreate"));
            delete(app.OptionsDialog);
           %delete(app.dataPort);
           %delete(app.configPort);
            delete(app)
        end

        % Menu selected function: UstawieniaMenu
        function UstawieniaMenuSelected(app, event)
            app.OptionsDialog = UstawieniaDialog(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AkcjeMenu = uimenu(app.UIFigure);
            app.AkcjeMenu.Text = 'Akcje';
            app.DodajurzdzenieMenu = uimenu(app.AkcjeMenu);
            app.DodajurzdzenieMenu.Text = 'Dodaj urządzenie';
            app.UsuurzdzenieMenu = uimenu(app.AkcjeMenu);
            app.UsuurzdzenieMenu.Text = 'Usuń urządzenie';
            app.MapaMenu = uimenu(app.UIFigure);
            app.MapaMenu.MenuSelectedFcn = createCallbackFcn(app, @MapaMenuSelected, true);
            app.MapaMenu.Text = 'Mapa';
            app.UstawieniaMenu = uimenu(app.UIFigure);
            app.UstawieniaMenu.MenuSelectedFcn = createCallbackFcn(app, @UstawieniaMenuSelected, true);
            app.UstawieniaMenu.Text = 'Ustawienia';
            app.GridForPlots = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridForPlots);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.PageNumber = uilabel(app.GridLayout2);
            app.PageNumber.Tag = 'PageNumber';
            app.PageNumber.Text = {'1'; ''};
            app.PoprzedniButton = uibutton(app.GridLayout2, 'push');
            app.PoprzedniButton.Text = {'Poprzedni'; ''};
            app.NastpnyButton = uibutton(app.GridLayout2, 'push');
            app.NastpnyButton.Text = 'Następny';
            app.TextAreaLabel = uilabel(app.GridForPlots);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.GridForPlots);
            app.TextArea2Label = uilabel(app.GridForPlots);
            app.TextArea2Label.Text = 'Text Area2';
            app.TextArea2 = uitextarea(app.GridForPlots);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjektSynchrofazory

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end